classdef OutputApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        ErrorVariancesTab       matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
        ModelTab                matlab.ui.container.Tab
        UITable2                matlab.ui.control.Table
        ModelLabel              matlab.ui.control.Label
        ModelDisplayArea        matlab.ui.control.TextArea
        ConfidenceIntervalsTab  matlab.ui.container.Tab
        UITable3                matlab.ui.control.Table
        EigenvaluePlotPanel     matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp;
        
        ModelType;
        
        Order;
        EigenValues;
        ErrorVariances;
        Model;
        ConfidenceIntervals;
        confidence_intervals_rounded;
        
        lag;
        coeff;
        noise_model;
        scaled_eig_values;
        number_of_variables;
        y_array = [];
        u_array = []; 
        stringarr = [];
        stry = [];
        stru = [];  
        temp_string;
        coeff_data;
        
        num_err_var;
        err_var_array;
        err_var_stringarr;
        err_str;        
        
        model_rounded;
        
    end

    methods (Access = public)
        
        function SetModelType(app, model_type)
            app.ModelType = model_type;
        end
        
        function SetOrder(app, order)
            disp('In SetOrder')
            app.Order               = order;
        end

        function DisplayEigenValues(app, eigen_values)
            
            disp('In DisplayEigenValues')
            app.EigenValues         = eigen_values;
            
            app.noise_model = NoiseModel.getInstance;
            
            
            if (app.noise_model.m_getNoiseModel() == NoiseModelEnums.NOISY)
                plot(app.UIAxes , log(app.EigenValues))
            else
                app.UIAxes.YLabel.String = "\lambda / \lambda_{max}";
                app.ErrorVariancesTab.Parent = [];
                app.scaled_eig_values = app.EigenValues/max(app.EigenValues);
                plot(app.UIAxes , app.scaled_eig_values);
                app.UIAxes.Title.String = "Plot of Eigenvalues " ;
            end
            
            if(app.ModelType == ModelTypeEnums.DYNAMIC)
                app.UIAxes.Title.String = "Plot of Log of Eigenvalues for Order = " + num2str(app.Order) + "(\eta = L-d+1)";
            end
            
        end    
    
        function DisplayErrorVariances(app, error_variances)
            disp('In DisplayErrorVariances')
            
            app.ResizeWindow();
            app.ShowTabGroup();
            
            %refresh(app.UIFigure)
            
            app.TabGroup.SelectedTab = app.ErrorVariancesTab;
            app.ErrorVariances       = error_variances;
            error_variances
            
            app.num_err_var        = size(error_variances, 1);
            
            app.err_var_array     = [];
            app.err_var_stringarr   = [];
            
            for i=1:app.num_err_var
                app.err_str    = [];
                app.err_str    = "Error Var in Col " + i ;
                app.err_var_stringarr = [app.err_var_stringarr ; app.err_str];
            end
            
            app.UITable.Data   = [cellstr(app.err_var_stringarr) num2cell(app.ErrorVariances)];
        end
    
        function DisplayModel(app, model)
            disp('In DisplayModel')
            app.TabGroup.SelectedTab = app.ModelTab;
%            app.Model                = model/model(size(model,1),1);
            app.Model                = model;
                        
            model
            
            if (app.ModelType == ModelTypeEnums.DYNAMIC)
                app.lag        = size(model,1)/2 - 1;
                app.coeff      = app.Model;
                
                app.y_array     = [];
                app.u_array     = [];
                app.stringarr   = [];
                
                for i=1:1:app.lag
                    app.stry    = [];
                    app.stru    = [];
                    app.stry    = "y(k-" + num2str(i) + ")";
                    app.stru    = "u(k-" + num2str(i) + ")";
                    app.y_array = [app.y_array ; app.stry];
                    app.u_array = [app.u_array ; app.stru];
                end
                
                app.y_array     = ["y"; app.y_array];
                app.u_array     = ["u"; app.u_array];
                app.stringarr   = [ app.u_array; app.y_array]
                app.coeff_data      = [ num2cell(app.coeff) app.stringarr];
                app.UITable2.Data   = [cellstr(app.stringarr) num2cell(app.coeff)];
            else
                app.number_of_variables = size(model,1);
                
                app.UITable2.Visible             = 'off';
                app.ModelDisplayArea.Visible     = 'on';
                %for i= 1:app.number_of_variables
                %    app.temp_string = "Error Variance in Column  " + i ;
                %    app.stringarr   = [ app.stringarr; app.temp_string];
                %end
                app.model_rounded = round(model', 2);
                app.ModelDisplayArea.Value = mat2str(app.model_rounded);
            end
            
           
        end
    
        function DisplayConfidenceIntervals(app, confidence_intervals)
            disp('In DisplayConfidenceIntervals')
            app.ConfidenceIntervalsTab.Parent   = app.TabGroup;
            app.TabGroup.SelectedTab            = app.ConfidenceIntervalsTab;
            app.ConfidenceIntervals             = confidence_intervals;
            confidence_intervals
            
            app.confidence_intervals_rounded = round(confidence_intervals, 2);
            
             if (app.ModelType == ModelTypeEnums.DYNAMIC)
                app.lag        = size(confidence_intervals,1)/2 - 1;
                
                
                app.y_array     = [];
                app.u_array     = [];
                app.stringarr   = [];
                app.coeff       = [];
                
                for i=1:size(app.confidence_intervals_rounded,1)
                    app.temp_string      = "[" + num2str(app.confidence_intervals_rounded(i,1)) ...
                                            + " , " + num2str(app.confidence_intervals_rounded(i,2)) + "]";
                    app.coeff            = [ app.coeff ; app.temp_string];              
                end
                
                for i=1:1:app.lag
                    app.stry    = [];
                    app.stru    = [];
                    app.stry    = "y(k-" + num2str(i) + ")";
                    app.stru    = "u(k-" + num2str(i) + ")";
                    app.y_array = [app.y_array ; app.stry];
                    app.u_array = [app.u_array ; app.stru];
                    
                end
                
                app.y_array     = ["y"; app.y_array];
                app.u_array     = ["u"; app.u_array];
                app.stringarr   = [ app.u_array; app.y_array]
                app.coeff_data      = [ cellstr(app.coeff) app.stringarr];
                app.UITable3.Data   = [cellstr(app.stringarr) cellstr(app.coeff)];
             else
                 disp("You should not be coming here if the model is not dynamic")
             end
           
        end
    end

    methods (Access = private)
    
         function ShowTabGroup(app)
            app.TabGroup.Position               = [1 1 450 300];
            app.TabGroup.Visible                = 'on';
        end
    
        function ResizeWindow(app)
            
            app.EigenvaluePlotPanel.Position    = [1   350 450 350];
            app.UIFigure.Position               = [685 100 450 700];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Position = [685 450 450 350];
            app.TabGroup.Visible = 'off';
            app.CallingApp  = mainapp;
            app.EigenvaluePlotPanel.Position = [1 1  450 350];
            app.UIAxes.Position              = [10 10 400 310];
            app.ModelDisplayArea.Visible     = 'off';
            app.ConfidenceIntervalsTab.Parent = [];
            app.ShowTabGroup();
            app.ResizeWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ErrorVariancesTab = uitab(app.TabGroup);
            app.ErrorVariancesTab.Title = 'Error Variances';
            app.UITable = uitable(app.ErrorVariancesTab);
            app.UITable.ColumnName = {'Variable'; 'Error Variance'};
            app.UITable.RowName = {};
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.UITable2 = uitable(app.ModelTab);
            app.UITable2.ColumnName = {'Variable'; 'Coefficient'};
            app.UITable2.RowName = {};
            app.ModelLabel = uilabel(app.ModelTab);
            app.ModelLabel.Text = 'Model';
            app.ModelDisplayArea = uitextarea(app.ModelTab);
            app.ConfidenceIntervalsTab = uitab(app.TabGroup);
            app.ConfidenceIntervalsTab.Title = 'Confidence Intervals';
            app.UITable3 = uitable(app.ConfidenceIntervalsTab);
            app.UITable3.ColumnName = {'Variable'; 'Interval'};
            app.UITable3.RowName = {};
            app.EigenvaluePlotPanel = uipanel(app.UIFigure);
            app.EigenvaluePlotPanel.Title = 'Eigenvalue Plot';
            app.UIAxes = uiaxes(app.EigenvaluePlotPanel);
            title(app.UIAxes, 'Plot of Log of Eigenvalues')
            xlabel(app.UIAxes, 'Eigenvalue index --->')
            ylabel(app.UIAxes, 'Log(eigenvalue)--->')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OutputApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end