classdef newUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        XPCAPanel                       matlab.ui.container.Panel
        ModelConfigurationPanel         matlab.ui.container.Panel
        LagEditFieldLabel               matlab.ui.control.Label
        LagEditField                    matlab.ui.control.NumericEditField
        NoiseVariancesButtonGroup       matlab.ui.container.ButtonGroup
        StaticButton                    matlab.ui.control.RadioButton
        DynamicButton                   matlab.ui.control.RadioButton
        NoInputsEditFieldLabel          matlab.ui.control.Label
        NoInputsEditField               matlab.ui.control.NumericEditField
        NoOutputsEditFieldLabel         matlab.ui.control.Label
        NoOutputsEditField              matlab.ui.control.NumericEditField
        NoiseVariancesButtonGroup_2     matlab.ui.container.ButtonGroup
        KnownButton                     matlab.ui.control.RadioButton
        UnknownButton                   matlab.ui.control.RadioButton
        NoiseModelButtonGroup           matlab.ui.container.ButtonGroup
        NoiselessButton                 matlab.ui.control.RadioButton
        NoisyButton                     matlab.ui.control.RadioButton
        ModelEstimationPanel            matlab.ui.container.Panel
        EstimateModelOrderButton        matlab.ui.control.Button
        EstimateNoiseVariancesButton    matlab.ui.control.Button
        BuildModelButton                matlab.ui.control.Button
        ModelValidationPanel            matlab.ui.container.Panel
        ComputeConfidenceIntervalsButton  matlab.ui.control.Button
        ComputeResidualsButton          matlab.ui.control.Button
        AdvOptionsButton                matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        DataProcessingPanel_2           matlab.ui.container.Panel
        LoadDataDropDownLabel           matlab.ui.control.Label
        LoadDataDropDown                matlab.ui.control.DropDown
        FileLabel                       matlab.ui.control.Label
        AdvancedOptionsDataProcessingPanel  matlab.ui.container.Panel
        AdvancedOptionsModelConfigurationPanel  matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        AdvancedOptionsModelEstimationPanel  matlab.ui.container.Panel
        ModelOrderEstLabel              matlab.ui.control.Label
        ModelOrderEstDropDown           matlab.ui.control.DropDown
        VarianceEstDropDownLabel        matlab.ui.control.Label
        VarianceEstDropDown             matlab.ui.control.DropDown
        AdvancedOptionsModelValidationPanel  matlab.ui.container.Panel
        SizeofBootstrapsEditFieldLabel  matlab.ui.control.Label
        SizeofBootstrapsEditField       matlab.ui.control.NumericEditField
        NoofBootstrapsEditFieldLabel    matlab.ui.control.Label
        NoofBootstrapsEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        STATIC_TYPE = 1;
        DYNAMIC_TYPE = 2;
        
        % Basic input data variables
        data;
        ModelType;
        NoOfInputsAndOutputs; %This is an App
        NoOfInputs;
        NoOfOutputs;
        Lag;
        
        % These flags are helpful in enabling/disabling features in UI
        IsNoiseless             = false;
        IsStatic                = true;
        IsModelOrderComputed    = false;
        IsNoiseVarianceComputed = false;
        IsModelComputed         = false;
        
        % Constructing these objects to invoke the backend code
        ModelObj;
        StaticObj ;
        DynamicObj;
        VarianceModelObj;
        OrderCalculatorObj
        
        XPCAObj;   % THE MAIN OBJECT TO WORK WITH BACKEND
        
        % Outputs from the Estimation algorithms
        Ordervalue = 0;
        Order = 0;
        variance_model;
        NoiseVariances;
        Eigenvalues;
        Model;
        
        FAILED = 0;
        SUCCESS = 1;
        
        % The Order value input App 
        OrderInputApp;
        
        % The output App to render the output data on UI
        OutApp;
        
        AdvancedOptions = false;
        
        NumberOfBootstraps = 100;
        SizeOfBootstraps   = 400;
        AlphaValue         = 5;
        ConfidenceIntervals;
    end
    
    methods (Access = public)
        
        function UpdateOrdervalue(app, order_value)
            app.Ordervalue = order_value;
        end
        
        % Not using now -- will be deprecated
        function UpdateNoOfInputsAndOutputs(app, no_of_inputs, no_of_outputs)
            
            app.NoOfInputs  = no_of_inputs;
            app.NoOfOutputs = no_of_outputs;
            app.NoOfInputs
            app.NoOfOutputs
        end
        
    end
    
    methods (Access = private)
        
        function ReloadUIAndInitializeDataObjects(app)
            
            if (app.IsStatic)
                app.ConfigureUIAndCreateObjectForStaticCase();
            else
                app.ConfigureUIAndCreateObjectForDynamicCase();
            end
            
            app.CreateVarianceObject();
            
        end
        
        function ConstructModelConfigurationObject(app)
            if (app.IsStatic)
                disp('create a static obj')
                app.ConstructStaticObject();
                
            else
                disp('create a dynamic obj')
                app.ConstructDynamicObject();
            end
        end
        
        %% Functions to Initialize the data objects
        function ConstructStaticObject(app)
            
            app.DynamicObj = [];
            
            app.StaticObj.ModelType                         = "Static";
            app.VarianceModelObj.VarianceCovarianceMapper   = "DIAGONAL_MAPPER";
        end
        
        function ConstructDynamicObject(app)
            app.UpdateDynamicObject();
        end
        
        function UpdateDynamicObject(app)
            
            app.StaticObj = [];
            
            app.DynamicObj.ModelType                        = "Dynamic";
            app.DynamicObj.Lag                              = app.LagEditField.Value;
            app.DynamicObj.NoOfInputs                       = app.NoInputsEditField.Value;
            app.DynamicObj.NoOfOutputs                      = app.NoOutputsEditField.Value;
            app.DynamicObj.ModelOrderCalculatorType         = app.ModelOrderEstDropDown.Value; %Check if this is necessary
            app.VarianceModelObj.VarianceCovarianceMapper   = "DIPCA_MAPPER";
        end
        
        function CreateVarianceObject(app)
            app.VarianceModelObj.VarianceModel              = "Unknown";
            app.VarianceModelObj.VarianceCovarianceMapper   = "DIPCA_MAPPER";
            app.VarianceModelObj.VarianceSolver             = app.VarianceEstDropDown.Value;
        end
        
        %% Functions to configure the UI in Static and Dynamic Case
        function ConfigureUIAndCreateObjectForStaticCase(app)
            
            app.DisableModelEstimationPanel();
            app.DisableModelValidationPanel();
            
            app.LagEditField.Visible                = 'off';
            app.LagEditFieldLabel.Visible           = 'off';
            app.NoInputsEditField.Visible           = 'off';
            app.NoInputsEditFieldLabel.Visible      = 'off';
            app.NoOutputsEditField.Visible          = 'off';
            app.NoOutputsEditFieldLabel.Visible     = 'off';
            %app.EstimateModelOrderButton.Visible    = 'off';
            %app.ModelOrderEstDropDown.Visible       = 'off';
            %app.ModelOrderEstLabel.Visible          = 'off';
            app.EstimateModelOrderButton.Visible    = 'on';
            app.ModelOrderEstDropDown.Visible       = 'on';
            app.ModelOrderEstLabel.Visible          = 'on';
            app.ConstructStaticObject();
            app.IsModelOrderComputed                = false;
            if (~app.IsNoiseless)
                app.IsNoiseVarianceComputed             = false;
            else
                app.IsNoiseVarianceComputed             = true;
            end
  
            app.RefreshModelEstimationPanel();
        end
        
        function ConfigureUIAndCreateObjectForDynamicCase(app)
            
            app.DisableModelEstimationPanel();
            app.DisableModelValidationPanel();
            
            app.LagEditField.Visible                = 'on';
            app.LagEditFieldLabel.Visible           = 'on';
            app.NoInputsEditField.Visible           = 'on';
            app.NoInputsEditFieldLabel.Visible      = 'on';
            app.NoOutputsEditField.Visible          = 'on';
            app.NoOutputsEditFieldLabel.Visible     = 'on';
            app.EstimateModelOrderButton.Visible    = 'on';
            app.ModelOrderEstDropDown.Visible       = 'on';
            app.ModelOrderEstLabel.Visible          = 'on';
            app.ConstructDynamicObject();
            app.IsModelOrderComputed                = false;
            
            if (~app.IsNoiseless)
                app.IsNoiseVarianceComputed             = false;
            else
                app.IsNoiseVarianceComputed             = true;
            end
            
            app.RefreshModelEstimationPanel();
        end


        %% Functions to handle the visibility of Advanced Options
        function HideAdvancedOptions(app)
            
            app.AdvOptionsButton.Text                           = "Adv. Options";
            app.UIFigure.Position                               = [100 100 260 700];
            app.AdvancedOptionsDataProcessingPanel.Visible      = 'off';
            app.AdvancedOptionsModelConfigurationPanel.Visible  = 'off';
            app.AdvancedOptionsModelEstimationPanel.Visible     = 'off';
            app.AdvancedOptionsModelValidationPanel.Visible     = 'off';
        end
        
        function ShowAdvancedOptions(app)
            
            app.AdvOptionsButton.Text                           = "Hide Adv. Options";
            app.UIFigure.Position                               = [100 100 580 700];
            app.AdvancedOptionsDataProcessingPanel.Visible      = 'on';
            app.AdvancedOptionsModelConfigurationPanel.Visible  = 'on';
            app.AdvancedOptionsModelEstimationPanel.Visible     = 'on';
            app.AdvancedOptionsModelValidationPanel.Visible     = 'on';
        end
        
        % Functions to disable the freeze the UI
        function DisableModelConfigurationPanel(app)
            
            app.NoiselessButton.Enable              = 'off';
            app.NoisyButton.Enable                  = 'off';
            app.StaticButton.Enable                 = 'off';
            app.DynamicButton.Enable                = 'off';
            app.KnownButton.Enable                  = 'off';
            app.UnknownButton.Enable                = 'off';
            app.LagEditField.Enable                 = 'off';
            app.NoInputsEditField.Enable            = 'off';
            app.NoOutputsEditField.Enable           = 'off';
            app.UITable.Enable                      = 'off';
        end
        
        function DisableModelEstimationPanel(app)
            
            app.EstimateModelOrderButton.Enable     = 'off';
            app.EstimateNoiseVariancesButton.Enable = 'off';
            app.BuildModelButton.Enable             = 'off';
            app.ModelOrderEstDropDown.Enable        = 'off';
            app.VarianceEstDropDown.Enable          = 'off';
        end
        
        function DisableModelValidationPanel(app)
            
            app.ComputeConfidenceIntervalsButton.Enable     = 'off';
            app.ComputeResidualsButton.Enable               = 'off';
            app.NoofBootstrapsEditField.Enable              = 'off';
            app.SizeofBootstrapsEditField.Enable            = 'off';
            
        end
        
        
        %% Functions to Enable UI features
        function EnableModelConfigurationPanel(app)
            
            
            app.NoiselessButton.Enable      = 'on';
            app.NoisyButton.Enable          = 'on';
            app.StaticButton.Enable         = 'on';
            app.DynamicButton.Enable        = 'on';
            
            if (~app.IsNoiseless)
                app.KnownButton.Enable      = 'on';
                app.UnknownButton.Enable    = 'on';
            else
                app.KnownButton.Enable      = 'off';
                app.UnknownButton.Enable    = 'off';
            end
        
            
            app.LagEditField.Enable     = 'on';
            app.UITable.Enable          = 'on';
        end
        
        function EnableModelEstimationPanel(app)
            
            app.DisableModelEstimationPanel();
            
            if (app.IsModelOrderComputed)
                
                app.EstimateModelOrderButton.Enable         = 'off';
                app.ModelOrderEstDropDown.Enable            = 'off';
            
 
                if (~app.IsNoiseless)
                    if (app.IsNoiseVarianceComputed == false)
                        app.EstimateNoiseVariancesButton.Enable = 'on';
                        app.VarianceEstDropDown.Enable          = 'on';
                    else
                        app.EstimateNoiseVariancesButton.Enable = 'off';
                        app.VarianceEstDropDown.Enable          = 'off';
                    end
                else
                    app.IsNoiseVarianceComputed             = true;
                    app.EstimateNoiseVariancesButton.Enable = 'off';
                    app.VarianceEstDropDown.Enable          = 'off';
                    
                end
                
                if (app.IsNoiseVarianceComputed)
                    app.BuildModelButton.Enable         = 'on';
                end
                
            else
                app.EstimateModelOrderButton.Enable         = 'on';
                app.ModelOrderEstDropDown.Enable            = 'on';
                
            end
            
            if (app.IsModelComputed)
                app.BuildModelButton.Enable = 'off';
            end
            
    end

        
        function EnableModelValidationPanel(app)
            
            app.ComputeConfidenceIntervalsButton.Enable = 'on';
            %app.ComputeResidualsButton.Enable           = 'on';
            
            if (~app.IsStatic)
                disp("These setting to be enabled later")
%                app.NoofBootstrapsEditField.Enable          = 'on';
%                app.SizeofBootstrapsEditField.Enable        = 'on';
            end
        end
        
        function RefreshModelEstimationPanel(app)
            app.EnableModelEstimationPanel();
        end
        
        %% Functions to Handle the Estimate of Order, Covariance and Model
        
        function InstantiateADynamicModel(app)
            
            app.XPCAObj = xpca(app.data);
            app.XPCAObj.m_setModel("DYNAMIC_MODEL");
            app.XPCAObj.m_setNoOfInputsAndOutputs(app.DynamicObj.NoOfInputs, app.DynamicObj.NoOfOutputs);
            app.XPCAObj.m_setPreprocessor("NULL");
            app.XPCAObj.m_preprocessData();
            app.XPCAObj.m_configureDynamicModel(app.DynamicObj.Lag);
            app.XPCAObj.m_stackDataByLag();
        
            if (~app.IsNoiseless)
                app.SetUpVarianceModel();
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISY);
            else
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISELESS);
            end
            
            app.ConfigureOrderCalculator();
            
            
        end
    
        function ConfigureOrderCalculator(app)
            % Check the Order calculator type and configure accordingly
            value = app.ModelOrderEstDropDown.Value;
            
            switch str2num(value)
                
                case struct(OrderCalculatorEnums.USER_DEFINED).Data
                    
                    disp(" Load order value in a another window and configure order calculator");
                    app.OrderInputApp = OrderValueInputApp(app);
                    waitfor(app.OrderInputApp)
                    
                    app.XPCAObj.m_configureOrderCalculatorUserDefinedOrder(app.Ordervalue);
                    
                case struct(OrderCalculatorEnums.HYPOTHESIS_TESTING).Data
                    
                   app.XPCAObj.m_configureOrderCalculatorForHypothesisTesting();
                    
                case struct(OrderCalculatorEnums.EIGEN_PLOTS).Data
                    
                    f = warndlg('Functionality Under Construction','Warning');
                    
                otherwise
                    
                    disp('UNDEFINED ORDER CALCULATOR TYPE')
            end
           
        end
        
        function InstantiateAStaticModel(app)
            
            app.XPCAObj = xpca(app.data);
            app.XPCAObj.m_setModel("STATIC_MODEL");
            
            if (~app.IsNoiseless)
                app.SetUpVarianceModel();
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISY);
            else
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISELESS);
            end
        
            app.ConfigureOrderCalculator();
        end
    
    % Initial set up for Variance model has MLE Solver as default. Can be changed later
        function SetUpVarianceModel(app)
            
            app.XPCAObj.m_setVarianceModel(upper(app.VarianceModelObj.VarianceModel));
            
            if (upper(app.VarianceModelObj.VarianceModel) == "UNKNOWN")
                
                if (app.IsStatic)
                    app.XPCAObj.m_configureVarianceModelForDiagonalMapper();
                else
                    app.XPCAObj.m_configureVarianceModelForDIPCA();
                end
                
                app.XPCAObj.m_setVarianceSolver("MLE_SOLVER");
                
                app.XPCAObj.m_initializeErrorVariances();
                
            else
                disp(' to set the error variances from UI')
            end
            
            
        end
        
        function ret_code = ComputeOrder(app)
            
            [app.Order, app.Eigenvalues] = app.XPCAObj.m_computeOrder()
            
            if ( app.Order == OrderCalculatorEnums.DEFAULT_ORDER())
                disp("The order could not be calculated")
                f = warndlg('Order Could not be Calculated: Check Lag','Warning');
                ret_code = app.FAILED;
            else
                if (~app.IsStatic)
                    app.XPCAObj.m_restackByOrder();
                end
                ret_code = app.SUCCESS;
            end
            
        end
        
        function ComputeVariances(app)
            
           if (app.DynamicButton.Value)
                app.XPCAObj.m_computeCovarianceStackedByLag();  
           else
               disp('To Initialize Static Object and set up variance model here and compute')
               [~, app.Eigenvalues] = app.XPCAObj.m_computeCovariancesForStaticCase();
           end
           
           app.variance_model = app.XPCAObj.m_getVarianceModel();
           app.NoiseVariances = app.variance_model.m_getErrorVariances();
           
        end
        
        function BuildModel(app)
            app.Model = app.XPCAObj.m_computeModel()          
        end
    
        function ComputeConfidenceIntervals(app)
            disp('compute confidence intervals here')
            app.ConfidenceIntervals = app.XPCAObj.m_computeConfidenceIntervals(app.NumberOfBootstraps, ...
                                                                                app.SizeOfBootstraps, ...
                                                                                app.AlphaValue); 
        end
        
        function output_app = GetOutputApp(app)
            
           if (isempty(app.OutApp) || ~isvalid(app.OutApp)) 
                disp('out app is empty. Instantiating')
                app.OutApp = OutputApp(app);
           end
           output_app = app.OutApp;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.DisableModelConfigurationPanel();
            app.DisableModelEstimationPanel();
            app.DisableModelValidationPanel();
            
            app.HideAdvancedOptions();
            %app.ReloadUIAndInitializeDataObjects();
            %app.ConstructModelConfigurationObject();
            
        end

        % Button pushed function: EstimateModelOrderButton
        function ComputeOrderButton(app, event)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Computing the Order');
            
            app.DisableModelConfigurationPanel();
            app.LagEditField.Enable                 = 'on';
            app.IsModelOrderComputed                = true;
            disp(' send the data to the backend and instantiate a dynamic object and compute order')
            
            if (~app.IsStatic)
                app.InstantiateADynamicModel();
            else
                app.InstantiateAStaticModel();
            end
            
            d.Value = .33; 
            d.Message = 'Computing Order';
            pause(1)
            
            if (app.ComputeOrder())
            
                d.Value = .67;
                d.Message = 'Processing the data';
                pause(1)

                %app.RefreshModelEstimationPanel();
            
                d.Value = 1;
                d.Message = 'Rendering Output';
                pause(1)

                if (~app.IsStatic)
                    SetModelType(app.GetOutputApp(), ModelTypeEnums.DYNAMIC);
                else
                    SetModelType(app.GetOutputApp(), ModelTypeEnums.STATIC);
                end
                
                SetOrder(app.GetOutputApp(), app.Order);
                DisplayEigenValues(app.GetOutputApp(), app.Eigenvalues);
            end
            
            close(d)
            app.RefreshModelEstimationPanel();
        end

        % Value changed function: LoadDataDropDown
        function LoadDataDropdown(app, event)
            
            value = app.LoadDataDropDown.Value;
            
            if (value ==  "Data File")
                [file,path] = uigetfile('*.mat')
                filename = fullfile(path, file);
                
                S = load(filename);
                names = fieldnames(S);
                
                
                if (length(names) == 1)
                    
                    app.data = S.(names{1});
                    app.LoadDataDropDown.Enable = false;
                    app.FileLabel.Text = "File Loaded: " + file;
                    app.FileLabel.Tooltip = app.FileLabel.Text;
                    
                else
                    if (numel(S) == 0)
                        f = warndlg('No data variable in the file','Warning');
                        app.LoadDataDropDown.Value = "Select Input Type";
                        return;
                    else
                        f = warndlg('Check to see if the input file contains only one variable','Warning');
                        app.LoadDataDropDown.Value = "Select Input Type";
                        return;
                    end
                end

                app.EnableModelConfigurationPanel();
                app.ReloadUIAndInitializeDataObjects();
                
            elseif (value ==  "Load from Environment")
                disp('loading from environment: Check if the variables are of same size and construct the data matrix')
                f = warndlg('Functionality Under Construction','Warning');
                app.LoadDataDropDown.Value = "Select Input Type";
            end
            
        end

        % Selection changed function: NoiseVariancesButtonGroup
        function ModelTypeChanged(app, event)
            
            selectedButton = app.NoiseVariancesButtonGroup.SelectedObject
            
            app.IsStatic = ~(app.IsStatic)
            
            app.ReloadUIAndInitializeDataObjects();
            app.ConstructModelConfigurationObject();
            
        end

        % Value changed function: LagEditField
        function LagChangedHandler(app, event)
            value    = app.LagEditField.Value;
            app.IsModelOrderComputed = false;
            app.IsModelComputed      = false;
            app.RefreshModelEstimationPanel();
            %app.Lag  = value;
            app.UpdateDynamicObject();
        end

        % Selection changed function: NoiseVariancesButtonGroup_2
        function NoiseVarianceOptionChanged(app, event)
            
            selectedButton = app.NoiseVariancesButtonGroup_2.SelectedObject;
            selectedButton
            
            if (selectedButton.Text == "Known")
                disp('Take Input Variances from pop up and update the varianceModelObj')
                f = warndlg('Functionality Under Construction','Warning');
                app.UnknownButton.Value = true;
            else
                disp('nothing much  to do')
            end
            
            if (app.UnknownButton.Value)
                app.VarianceModelObj.VarianceModel = upper("Unknown");
            else
                app.VarianceModelObj.VarianceModel = upper("Known");
            end
        end

        % Callback function
        function DPAOButtonClickedHandler(app, event)
            app.AdvancedOptionsDataProcessingPanel.Visible = 'on';
        end

        % Callback function
        function MCAOButtonClickedHandler(app, event)
            app.AdvancedOptionsModelConfigurationPanel.Visible = 'on';
        end

        % Callback function
        function MEAOButtonClickedHandler(app, event)
            app.AdvancedOptionsModelEstimationPanel.Visible ='on';
        end

        % Button pushed function: AdvOptionsButton
        function AdvancedOptionsButtonClickedHandler(app, event)
            app.AdvancedOptions = ~(app.AdvancedOptions);
            if ( app.AdvancedOptions)
                app.ShowAdvancedOptions();
            else
                app.HideAdvancedOptions();
            end
        end

        % Value changed function: ModelOrderEstDropDown
        function ModelOrderEstChanged(app, event)
            
            value = app.ModelOrderEstDropDown.Value;
            
            switch str2num(value)
                
                case struct(OrderCalculatorEnums.USER_DEFINED).Data
                    
                    disp("Order Calculator : User Defined");
                    
                case struct(OrderCalculatorEnums.HYPOTHESIS_TESTING).Data
                    
                    %app.DynamicObj.ModelOrderCalculatorType = "HYPOTHESIS_TESTING";
                    app.UpdateDynamicObject();
                    
                case struct(OrderCalculatorEnums.EIGEN_PLOTS).Data
                    
                    f = warndlg('Functionality Under Construction','Warning');
                    app.ModelOrderEstDropDown.Value = num2str(struct(OrderCalculatorEnums.HYPOTHESIS_TESTING).Data);
                    
                otherwise
                    
                    disp('UNDEFINED ORDER CALCULATOR TYPE')
                    
            end

            
            %if (value ~= "Hypothesis Testing")
            %    f = warndlg('Functionality Under Construction','Warning');
            %    app.ModelOrderEstDropDown.Value = "Hypothesis Testing";
            %else
            %    disp("setting model order calculator successful")
            %end
            
            %app.DynamicObj.ModelOrderCalculatorType = app.ModelOrderEstDropDown.Value;
            
        end

        % Value changed function: VarianceEstDropDown
        function VarianceEstDropDownValueChanged(app, event)
            
            
            value = app.VarianceEstDropDown.Value;
            
            if (value ~= "Maximum Likelihood Estimator")
                f = warndlg('Functionality Under Construction','Warning');
                app.VarianceEstDropDown.Value       = "Maximum Likelihood Estimator";    
            else
                disp("setting variance model successful")    
            end
            
            app.VarianceModelObj.VarianceSolver     = app.VarianceEstDropDown.Value;
            
        end

        % Button pushed function: BuildModelButton
        function BuildModelButtonClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Computing the Model');
        
            disp('build model using the variances')
            
            d.Value = 0.3;
            d.Message = 'Computing Model';
            pause(1)
            
            app.BuildModel();
            app.IsModelComputed = true;
            
            if (~app.IsStatic)
                app.EnableModelValidationPanel();
            end
            
            d.Value = 1;
            d.Message = 'Rendering Output';
            pause(1)
            
            DisplayModel(app.GetOutputApp(), app.Model);
            close(d);
            app.RefreshModelEstimationPanel();
        end

        % Button pushed function: EstimateNoiseVariancesButton
        function EstimateNoiseVarianceButtonClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Computing Error Variance');
            
            app.DisableModelConfigurationPanel();
            
            disp('Check Static or Dynamic Case; static createobj and compute; dynamic just compute');
            
%            if (app.IsStatic)
%                app.InstantiateAStaticModel();
%            end
                
            d.Value = 0.3;
            d.Message = 'Computing Error Variance';
            pause(1)
            
            app.ComputeVariances();
            
            app.IsNoiseVarianceComputed = true;    
%            app.RefreshModelEstimationPanel();
            
            if (app.IsStatic)
                SetModelType(app.GetOutputApp(), ModelTypeEnums.STATIC);
            end
            % Redundant, but useful when the user closes the Ouput window and computes error variances
            DisplayEigenValues(app.GetOutputApp(), app.Eigenvalues);
            
            d.Value = 1;
            d.Message = 'Rendering the Output';
            pause(1)
            
            DisplayErrorVariances(app.GetOutputApp(), app.NoiseVariances);
            
            close(d);
            app.RefreshModelEstimationPanel();
        end

        % Value changed function: NoInputsEditField
        function NoInputsEditFieldValueChanged(app, event)
                value = app.NoInputsEditField.Value;
                app.UpdateDynamicObject();
        end

        % Value changed function: NoOutputsEditField
        function NoOutputsEditFieldValueChanged(app, event)
                value = app.NoOutputsEditField.Value;
                app.UpdateDynamicObject();
        end

        % Button pushed function: ComputeConfidenceIntervalsButton
        function ComputeConfidenceIntervalsButtonPushed(app, event)
             
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Computing the Confidence Intervals');
        
            disp('build model using the variances')
            
            d.Value = 0.3;
            d.Message = 'Computing Confidence Intervals';
            pause(1)
            
            app.ComputeConfidenceIntervals();
            
            
            d.Value = 1;
            d.Message = 'Rendering Output';
            pause(1)
            
           
            DisplayConfidenceIntervals(app.GetOutputApp(), app.ConfidenceIntervals);
            close(d);
            app.ComputeConfidenceIntervalsButton.Enable = 'off';    
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            
            if (app.IsStatic)
                
                data_mat   = app.data;
                model_type = "Static";
                
                noise_var  = app.NoiseVariances;
                model      = app.Model;
                     
                vars = {'data_mat', 'model_type', 'noise_var', 'model'};
            else
                
                data_mat   = app.data;
                model_type = "Dynamic";
                
                order       = app.Order
                noise_var   = app.NoiseVariances;
                model       = app.Model
                confidence_intervals = app.ConfidenceIntervals;
           
                vars = {'data_mat', 'model_type', 'order', 'noise_var', 'model', 'confidence_intervals'};
            end
            date_str = datestr(datetime('now'));
            uisave( vars, strcat('xpca-', date_str(isstrprop(date_str,'alphanum'))))
        end

        % Selection changed function: NoiseModelButtonGroup
        function NoiseButtonChangedHandler(app, event)
            selectedButton = app.NoiseModelButtonGroup.SelectedObject;
            
            app.IsNoiseless = ~(app.IsNoiseless)
            app.IsNoiseVarianceComputed = false;
            
            app.EnableModelConfigurationPanel();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.XPCAPanel = uipanel(app.UIFigure);
            app.XPCAPanel.Title = 'XPCA';
            app.ModelConfigurationPanel = uipanel(app.UIFigure);
            app.ModelConfigurationPanel.Title = 'Model Configuration';
            app.LagEditFieldLabel = uilabel(app.ModelConfigurationPanel);
            app.LagEditFieldLabel.Text = 'Lag';
            app.LagEditField = uieditfield(app.ModelConfigurationPanel, 'numeric');
            app.LagEditField.Limits = [1 Inf];
            app.LagEditField.ValueDisplayFormat = '%.0f';
            app.LagEditField.ValueChangedFcn = createCallbackFcn(app, @LagChangedHandler, true);
            app.LagEditField.Value = 1;
            app.NoiseVariancesButtonGroup = uibuttongroup(app.ModelConfigurationPanel);
            app.NoiseVariancesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelTypeChanged, true);
            app.NoiseVariancesButtonGroup.Title = 'Noise Variances';
            app.StaticButton = uiradiobutton(app.NoiseVariancesButtonGroup);
            app.StaticButton.Text = 'Static';
            app.StaticButton.Value = true;
            app.DynamicButton = uiradiobutton(app.NoiseVariancesButtonGroup);
            app.DynamicButton.Text = 'Dynamic';
            app.NoInputsEditFieldLabel = uilabel(app.ModelConfigurationPanel);
            app.NoInputsEditFieldLabel.Text = 'No. Inputs';
            app.NoInputsEditField = uieditfield(app.ModelConfigurationPanel, 'numeric');
            app.NoInputsEditField.Limits = [1 Inf];
            app.NoInputsEditField.ValueDisplayFormat = '%.0f';
            app.NoInputsEditField.ValueChangedFcn = createCallbackFcn(app, @NoInputsEditFieldValueChanged, true);
            app.NoInputsEditField.Editable = 'off';
            app.NoInputsEditField.Enable = 'off';
            app.NoInputsEditField.Value = 1;
            app.NoOutputsEditFieldLabel = uilabel(app.ModelConfigurationPanel);
            app.NoOutputsEditFieldLabel.Text = 'No. Outputs';
            app.NoOutputsEditField = uieditfield(app.ModelConfigurationPanel, 'numeric');
            app.NoOutputsEditField.Limits = [1 Inf];
            app.NoOutputsEditField.ValueDisplayFormat = '%.0f';
            app.NoOutputsEditField.ValueChangedFcn = createCallbackFcn(app, @NoOutputsEditFieldValueChanged, true);
            app.NoOutputsEditField.Editable = 'off';
            app.NoOutputsEditField.Enable = 'off';
            app.NoOutputsEditField.Value = 1;
            app.NoiseVariancesButtonGroup_2 = uibuttongroup(app.ModelConfigurationPanel);
            app.NoiseVariancesButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @NoiseVarianceOptionChanged, true);
            app.NoiseVariancesButtonGroup_2.Title = 'Noise Variances';
            app.KnownButton = uiradiobutton(app.NoiseVariancesButtonGroup_2);
            app.KnownButton.Text = 'Known';
            app.UnknownButton = uiradiobutton(app.NoiseVariancesButtonGroup_2);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.NoiseModelButtonGroup = uibuttongroup(app.ModelConfigurationPanel);
            app.NoiseModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseButtonChangedHandler, true);
            app.NoiseModelButtonGroup.Title = 'Noise Model';
            app.NoiselessButton = uiradiobutton(app.NoiseModelButtonGroup);
            app.NoiselessButton.Text = 'Noiseless';
            app.NoisyButton = uiradiobutton(app.NoiseModelButtonGroup);
            app.NoisyButton.Text = 'Noisy';
            app.NoisyButton.Value = true;
            app.ModelEstimationPanel = uipanel(app.UIFigure);
            app.ModelEstimationPanel.Title = 'Model Estimation';
            app.EstimateModelOrderButton = uibutton(app.ModelEstimationPanel, 'push');
            app.EstimateModelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeOrderButton, true);
            app.EstimateModelOrderButton.Text = 'Estimate Model Order';
            app.EstimateNoiseVariancesButton = uibutton(app.ModelEstimationPanel, 'push');
            app.EstimateNoiseVariancesButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateNoiseVarianceButtonClicked, true);
            app.EstimateNoiseVariancesButton.Text = 'Estimate Noise Variances';
            app.BuildModelButton = uibutton(app.ModelEstimationPanel, 'push');
            app.BuildModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildModelButtonClicked, true);
            app.BuildModelButton.Text = 'Build Model';
            app.ModelValidationPanel = uipanel(app.UIFigure);
            app.ModelValidationPanel.Title = 'Model Validation';
            app.ComputeConfidenceIntervalsButton = uibutton(app.ModelValidationPanel, 'push');
            app.ComputeConfidenceIntervalsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeConfidenceIntervalsButtonPushed, true);
            app.ComputeConfidenceIntervalsButton.Text = 'Compute Confidence Intervals';
            app.ComputeResidualsButton = uibutton(app.ModelValidationPanel, 'push');
            app.ComputeResidualsButton.Enable = 'off';
            app.ComputeResidualsButton.Text = 'Compute Residuals';
            app.AdvOptionsButton = uibutton(app.ModelValidationPanel, 'push');
            app.AdvOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedOptionsButtonClickedHandler, true);
            app.AdvOptionsButton.Text = 'Adv. Options';
            app.SaveDataButton = uibutton(app.ModelValidationPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.DataProcessingPanel_2 = uipanel(app.UIFigure);
            app.DataProcessingPanel_2.Title = 'Data Processing';
            app.LoadDataDropDownLabel = uilabel(app.DataProcessingPanel_2);
            app.LoadDataDropDownLabel.Text = 'Load Data';
            app.LoadDataDropDown = uidropdown(app.DataProcessingPanel_2);
            app.LoadDataDropDown.Items = {'Select Input Type', 'Data File', 'Load from Environment'};
            app.LoadDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadDataDropdown, true);
            app.LoadDataDropDown.Value = 'Select Input Type';
            app.FileLabel = uilabel(app.DataProcessingPanel_2);
            app.FileLabel.Text = '';
            app.AdvancedOptionsDataProcessingPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsDataProcessingPanel.Title = 'Advanced Options - Data Processing';
            app.AdvancedOptionsModelConfigurationPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsModelConfigurationPanel.Title = 'Advanced Options - Model Configuration';
            app.UITable = uitable(app.AdvancedOptionsModelConfigurationPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.AdvancedOptionsModelEstimationPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsModelEstimationPanel.Title = 'Advanced Options - Model Estimation';
            app.ModelOrderEstLabel = uilabel(app.AdvancedOptionsModelEstimationPanel);
            app.ModelOrderEstLabel.Text = 'Model Order Est.';
            app.ModelOrderEstDropDown = uidropdown(app.AdvancedOptionsModelEstimationPanel);
            app.ModelOrderEstDropDown.Items = {'Select Order Est. Method', 'User Defined', 'Hypothesis Testing', 'Eigenplots'};
            app.ModelOrderEstDropDown.ItemsData = {'0', '1', '2', '3'};
            app.ModelOrderEstDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelOrderEstChanged, true);
            app.ModelOrderEstDropDown.Enable = 'off';
            app.ModelOrderEstDropDown.Value = '2';
            app.VarianceEstDropDownLabel = uilabel(app.AdvancedOptionsModelEstimationPanel);
            app.VarianceEstDropDownLabel.Text = 'Variance  Est.';
            app.VarianceEstDropDown = uidropdown(app.AdvancedOptionsModelEstimationPanel);
            app.VarianceEstDropDown.Items = {'Select Variance Est. Method', 'Keller''s Solution', 'Maximum Likelihood Estimator'};
            app.VarianceEstDropDown.ValueChangedFcn = createCallbackFcn(app, @VarianceEstDropDownValueChanged, true);
            app.VarianceEstDropDown.Enable = 'off';
            app.VarianceEstDropDown.Value = 'Maximum Likelihood Estimator';
            app.AdvancedOptionsModelValidationPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsModelValidationPanel.Title = 'Advanced Options - Model Validation';
            app.SizeofBootstrapsEditFieldLabel = uilabel(app.AdvancedOptionsModelValidationPanel);
            app.SizeofBootstrapsEditFieldLabel.Text = 'Size of Bootstraps';
            app.SizeofBootstrapsEditField = uieditfield(app.AdvancedOptionsModelValidationPanel, 'numeric');
            app.NoofBootstrapsEditFieldLabel = uilabel(app.AdvancedOptionsModelValidationPanel);
            app.NoofBootstrapsEditFieldLabel.Text = 'No. of Bootstraps';
            app.NoofBootstrapsEditField = uieditfield(app.AdvancedOptionsModelValidationPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end